classdef vrepmatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        PathFollowingButton        matlab.ui.control.Button
        CreatePathButton           matlab.ui.control.Button
        BugAlgorithmButton         matlab.ui.control.Button
        CreateMapButton            matlab.ui.control.Button
        PlotTrajectoryButton       matlab.ui.control.Button
        BugAlgorithmLabel          matlab.ui.control.Label
        AAlgorithmLabel            matlab.ui.control.Label
        RunKinematicButton         matlab.ui.control.Button
        CheckKinematicLabel        matlab.ui.control.Label
        PlotTrajectoryButtonBug    matlab.ui.control.Button
        PlotTrajectoryButtonAstar  matlab.ui.control.Button
        RunInverseKinematicButton  matlab.ui.control.Button
        INSButton                  matlab.ui.control.Button
        CheckINSLabel              matlab.ui.control.Label
        PlotTrajectoryINS          matlab.ui.control.Button
    end


    methods (Access = public)
        
function [R,Accel_cov_mat]= getCovMat(Pos_sample,Accel_sample)
     Pos_deviation = Pos_sample - ones(length(Pos_sample),length(Pos_sample))*(Pos_sample)*(1/length(Pos_sample))
     Pos_cov = transpose(Pos_deviation)*(Pos_deviation)
     Accel_deviation = Accel_sample - ones(length(Accel_sample),length(Accel_sample))*(Accel_sample)*(1/length(Accel_sample))
     Accel_cov = transpose(Accel_deviation)*(Accel_deviation)
     R = diag([Pos_cov(1,1),Pos_cov(2,2),Pos_cov(3,3),0.1,0.1,0.1])
     Accel_cov_mat = diag([Accel_cov(1,1),Accel_cov(2,2),Accel_cov(3,3),Accel_cov(1,1),Accel_cov(2,2),Accel_cov(3,3)]) 
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreatePathButton
        function CreatePathButtonPushed(app, event)
            global map start_node endnode path visited_a
           % astarturtle_differential
           %% user inputs
% startpoint
prompt='please enter the startpoint in the form [x y theta]: ';
start_node=input(prompt);
% endpoint
prompt='please enter the endpoint in the form[x y theta]: ';
endnode=input(prompt);
% rpm vector
rpm_vec=[50,60];
%% checking for user inputs
while(map(floor(start_node(1)),floor(start_node(2)))==2)
    prompt='the node you entered is in obstacle please enter correct startnode: ';
    start_node=input(prompt);
end
global map
while(map(floor(endnode(1)),floor(endnode(2)))==2)
    prompt='the node you entered is in obstacle please enter correct endnode: ';
    endnode=input(prompt);
end
while(out_bounds(start_node)==1)
    prompt='the node you entered is out of bounds please enter correct startnode: ';
    start_node=input(prompt);
end
while(out_bounds(endnode)==1)
    prompt='the node you entered is out of bounds please enter correct endnode: ';
    endnode=input(prompt);
end
%% parameters for consideration in cm
global r
r=3.5;
global L
L=23;
global clearance
clearance=210;
t=1;


%% Algorithm (A*)
node=1;
res=10;
visited_arr=zeros(floor(11100/res),floor(10100/res));
global visited_a
visited_a=[];
cost_arr =inf(floor(11100/res),floor(10100/res));
cost_arr(1,1)=0;
cost_goarr=zeros(floor(11100/res),floor(10100/res));
cost_comearr=zeros(floor(11100/res),floor(10100/res));
parent_arr=cell(floor(11100/res),floor(10100/res));
rpm_arr=cell(floor(11100/res),floor(10100/res));
prune_arr=zeros(111,101);
q=[];
q=start_node;
z=start_node;
while(endcircle(endnode,z)==0)
    %marking startnode as visited
    visited_arr(start_node(1),start_node(2))=1;
    [z,q]=pop(q,cost_arr);
    [status,newnode]= movestraight(z,rpm_vec,t,map);
    if(status==1&&prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))==0)
        prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))=1;
        rpm_arr(newnode(1),newnode(2))={[rpm_vec(1) rpm_vec(1)]};
        value=visited(visited_arr,newnode);
        if (value==0)
            visited_arr(newnode(1),newnode(2))=1;
            visited_a=cat(1,visited_a,newnode);
            q=insert(q,newnode);
            % update the parent
            parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
            [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
            cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+((2*rpm_vec(1)*pi*r*t)/60);
            cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
        else
            if(cost_comearr(newnode(1),newnode(2))>cost_comearr(z(1),z(2))+((2*rpm_vec(1)*pi*r*t)/60))
               [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
               cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+((2*rpm_vec(1)*pi*r*t)/60);
               cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
               parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};   
            end
               
        end
    end
    
    [status,newnode]= movestraight_l(z,rpm_vec,t,map);
    if (status==1&&prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))==0)
        prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))=1;
        value=visited(visited_arr,newnode);
        rpm_arr(newnode(1),newnode(2))={[rpm_vec(2) rpm_vec(2)]};
        if (value==0)
            visited_arr(newnode(1),newnode(2))=1;
            visited_a=cat(1,visited_a,newnode);
            q=insert(q,newnode);
            parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
            [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
            cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+((2*rpm_vec(2)*pi*r*t)/60);
            cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
            
        else
            if(cost_comearr(newnode(1),newnode(2))>cost_comearr(z(1),z(2))+((2*rpm_vec(2)*pi*r*t)/60))
                [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
                cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+((2*rpm_vec(2)*pi*r*t)/60);
                cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
                parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
              
            end
       end
         
    end
    
    [status,newnode]= movemoreleft(z,rpm_vec,t,map);
    if (status==1&&prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))==0)
        prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))=1;
        value=visited(visited_arr,newnode);
        rpm_arr(newnode(1),newnode(2))={[0 rpm_vec(1)]};
        if (value==0)
            visited_arr(newnode(1),newnode(2))=1;
            visited_a=cat(1,visited_a,newnode);
            q=insert(q,newnode);
            parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
            [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
            cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+((rpm_vec(1)*pi*r*t)/60);
            cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
            
        else
            if(cost_comearr(newnode(1),newnode(2))>cost_comearr(z(1),z(2))+((rpm_vec(1)*pi*r*t)/60))
                [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
                cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+((rpm_vec(1)*pi*r*t)/60);
                cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
                parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
                
            end
        end
            
    end
    
    [status,newnode]= movemoreleft_l(z,rpm_vec,t,map);
    if (status==1&&prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))==0)
        prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))=1;
        value=visited(visited_arr,newnode);
        rpm_arr(newnode(1),newnode(2))= {[0 rpm_vec(2)]};
        if (value==0)
            visited_arr(newnode(1),newnode(2))=1;
            visited_a=cat(1,visited_a,newnode);
            q=insert(q,newnode);
            parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
            [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
            cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+((rpm_vec(2)*pi*r*t)/60);
            cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;      
            
        else
            if(cost_comearr(newnode(1),newnode(2))>cost_comearr(z(1),z(2))+((rpm_vec(2)*pi*r*t)/60))
                [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
                cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+((rpm_vec(2)*pi*r*t)/60);
                cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
                parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
               
            end
        end
    end
    
    [status,newnode]= movemoreright(z,rpm_vec,t,map);
    if (status==1&&prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))==0)
        prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))=1;
        value=visited(visited_arr,newnode);
        rpm_arr(newnode(1),newnode(2))={[rpm_vec(1) 0]};
        if (value==0)
            visited_arr(newnode(1),newnode(2))=1;
            visited_a=cat(1,visited_a,newnode);
            q=insert(q,newnode);
            parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
            [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
            cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+((rpm_vec(1)*pi*r*t)/60);
            cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
             
             
         else
            if(cost_comearr(newnode(1),newnode(2))>cost_comearr(z(1),z(2))+((rpm_vec(1)*pi*r*t)/60))
                [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
                cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+((rpm_vec(1)*pi*r*t)/60);
                cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
                parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
            end
         end
    end
    
    [status,newnode]= movemoreright_l(z,rpm_vec,t,map);
    if (status==1&&prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))==0)
        prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))=1;
        value=visited(visited_arr,newnode);
        rpm_arr(newnode(1),newnode(2))={[rpm_vec(2) 0]};
        if (value==0)
            visited_arr(newnode(1),newnode(2))=1;
            visited_a=cat(1,visited_a,newnode);
            q=insert(q,newnode);
            parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
            [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
            cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+((rpm_vec(2)*pi*r*t)/60);
            cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
            
            
        else
            if(cost_comearr(newnode(1),newnode(2))>cost_comearr(z(1),z(2))+((rpm_vec(2)*pi*r*t)/60))
                [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
                cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+((rpm_vec(2)*pi*r*t)/60);
                cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
                parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
               
            end
        end
    end
    [status,newnode]= movebit_left(z,rpm_vec,t,map);
    if (status==1&&prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))==0)
        prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))=1;
        value=visited(visited_arr,newnode);
        rpm_arr(newnode(1),newnode(2))={[rpm_vec(1) rpm_vec(2)]};
        if (value==0)
            visited_arr(newnode(1),newnode(2))=1;
            visited_a=cat(1,visited_a,newnode);
            q=insert(q,newnode);
            parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
            [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
            cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+(((rpm_vec(1)+rpm_vec(2))*pi*r*t)/60);
            cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
           
            
        else
            if( cost_comearr(newnode(1),newnode(2))>cost_comearr(z(1),z(2))+(((rpm_vec(1)+rpm_vec(2))*pi*r*t)/60))
                [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
                cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+(((rpm_vec(1)+rpm_vec(2))*pi*r*t)/60);
                cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
                parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
            end
        end
    end
    [status,newnode]= movebit_right(z,rpm_vec,t,map);
    if (status==1&&prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))==0)
        prune_arr(floor(newnode(1)/10),floor(newnode(2)/10))=1;
        value=visited(visited_arr,newnode);
        rpm_arr(newnode(1),newnode(2))={[rpm_vec(2) rpm_vec(1)]};
        if (value==0)
            visited_arr(newnode(1),newnode(2))=1;
            visited_a=cat(1,visited_a,newnode);
            q=insert(q,newnode);
            parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};
            [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
            cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+(((rpm_vec(1)+rpm_vec(2))*pi*r*t)/60);
            cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
            
        else
            if(cost_comearr(newnode(1),newnode(2))>cost_comearr(z(1),z(2))+(((rpm_vec(1)+rpm_vec(2))*pi*r*t)/60))
                [c,cost_goarr]=cost_go(cost_goarr,newnode,endnode);
                cost_comearr(newnode(1),newnode(2))=cost_comearr(z(1),z(2))+(((rpm_vec(1)+rpm_vec(2))*pi*r*t)/60);
                cost_arr(newnode(1),newnode(2))=cost_comearr(newnode(1),newnode(2))+c;
                parent_arr(newnode(1),newnode(2))={[z(1) z(2) z(3)]};               
            end
        end
       
    end
end
%% backtracking
a=z;
j=z;
while(isequal(j,[start_node(1),start_node(2),start_node(3)])~=1)
    temp=parent_arr(j(1),j(2));
    j=cell2mat(temp);
    a=cat(1,a,j);
end
global path
a=fliplr(a');
path=a';
path=path;
[row,col]=find(visited_arr==1);
b=cat(2,row,col);
% finding rpms
path_rpm=zeros(1,2);
for i= 2:size(path,1)
path_rpm = cat(1,path_rpm,cell2mat(rpm_arr(path(i,1),path(i,2))));
end
path_rpm(1,:)=[];


%% draw a circle around goal node
function [f]= endcircle(endnode,nod)
end_circle=(nod(1)-endnode(1))^2 + (nod(2)-endnode(2))^2 -(10)^2;
if(end_circle<=0)
    f=1;
else
    f=0;
end
end
%% cost to go computing
function[c,cost_goarr]=cost_go(cost_goarr,newnode,endnode)
%euclidean distance
cost_goarr(newnode(1),newnode(2))=sqrt((newnode(1)-endnode(1))^2 + (newnode(2)-endnode(2))^2);
c=cost_goarr(newnode(1),newnode(2));
end
%% insert
 function [q]= insert(q,newnode)
 q=cat(1,q,newnode);
 end
 
 %% pop
 function [inst,s]= pop(s,cost_arr)
 min=1;
 minx=s(1,1);
 miny=s(1,2);
 for i=1:size(s,1)
     x=s(i,1);
     y=s(i,2);
     if(cost_arr(x,y)<cost_arr(minx,miny))
         min=i;
         minx=x;
         miny=y;
         inst=s(min,:);
        %delete the element from queue
     end
         
 end
 inst=s(min,:);
 s(min,:)=[];
 end
%% check wether visited or not
function [value]= visited(visited_arr,newnode)
if(visited_arr(newnode(1),newnode(2))==1)
    value=1;
else
    value=0;
end
end


%% outof bounds check
function[flag]= out_bounds(newnode)
clearance=210;
if(newnode(1)<=floor((0+(clearance/10)))||newnode(2)<=floor((0+(clearance/10)))||newnode(1)>=floor((1110-(clearance/10)))||newnode(2)>=floor((1010-(clearance/10))))
    flag=1; % out of bounds
else
    flag=0;
end
end
%% action set
% for the case with [rpm1 rpm1]move straight
function[status,newnode]= movestraight(currentnode,rpm_vec,t,map)

th=currentnode(3);
dx=0;
dy=0;
    for k=1:100
        dx=dx+(2*pi*(r/60)*(rpm_vec(1))*cosd(th)*(t/100));
        dy=dy+(2*pi*(r/60)*(rpm_vec(1))*sind(th)*(t/100));
        if out_bounds([currentnode(1)+dx,currentnode(2)+dy])==0
            if (map(round(currentnode(1)+dx),round(currentnode(2)+dy))==2)
                 status=0;
            end
        end
    end
newnode(3)=currentnode(3);
newnode(1)=round(currentnode(1)+dx);
newnode(2)=round(currentnode(2)+dy);
   if out_bounds(newnode)==0
       if (map(newnode(1),newnode(2))==0)
       status=1;
       else
       newnode=[];
       status=0;
       end
   else
       newnode=[];
       status=0;
   end
end
% for [rpm2 rpm2]
function[status,newnode]= movestraight_l(currentnode,rpm_vec,t,map)

th=currentnode(3);
dx=0;
dy=0;
    for k=1:100
        dx=dx+(2*pi*(r/60)*(rpm_vec(1))*cosd(th)*(t/100));
        dy=dy+(2*pi*(r/60)*(rpm_vec(1))*sind(th)*(t/100));
        if out_bounds([currentnode(1)+dx,currentnode(2)+dy])==0
            if (map(round(currentnode(1)+dx),round(currentnode(2)+dy))==2)
                 status=0;
            end
        end
    end
newnode(3)=currentnode(3);
newnode(1)=round(currentnode(1)+dx);
newnode(2)=round(currentnode(2)+dy);
    if out_bounds(newnode)==0
       if (map(newnode(1),newnode(2))==0)
       status=1;
       else
       newnode=[];
       status=0;
       end
   else
       newnode=[];
       status=0;
    end
end
% for case with [0 rpm1]
function[status,newnode]= movemoreleft(currentnode,rpm_vec,t,map)


th=currentnode(3);
dx=0;
dy=0;
    for k=1:100
        dx=dx+(2*pi*(r/120)*(rpm_vec(1))*cosd(th)*(t/100));
        dy=dy+(2*pi*(r/120)*(rpm_vec(1))*sind(th)*(t/100));
        dtheta=6*(r/L)*(rpm_vec(1))*(t/100);
        th=th+dtheta;
        if out_bounds([currentnode(1)+dx,currentnode(2)+dy])==0
            if (map(round(currentnode(1)+dx),round(currentnode(2)+dy))==2)
                 status=0;
            end
        end
    end
newnode(3)=th;
newnode(1)=round(currentnode(1)+dx);
newnode(2)=round(currentnode(2)+dy);
    if out_bounds(newnode)==0
       if (map(newnode(1),newnode(2))==0)
       status=1;
       else
       newnode=[];
       status=0;
       end
   else
       newnode=[];
       status=0;
   end
end
% for [0 rpm2]
function[status,newnode]= movemoreleft_l(currentnode,rpm_vec,t,map)

th=currentnode(3);
dx=0;
dy=0;
    for k=1:100
        dx=dx+(2*pi*(r/120)*(rpm_vec(2))*cosd(th)*(t/100));
        dy=dy+(2*pi*(r/120)*(rpm_vec(2))*sind(th)*(t/100));
        dtheta=6*(r/L)*(rpm_vec(2))*(t/100);
        th=th+dtheta;
        if out_bounds([currentnode(1)+dx,currentnode(2)+dy])==0
            if (map(round(currentnode(1)+dx),round(currentnode(2)+dy))==2)
                 status=0;
            end
        end
    end
newnode(3)=th;
newnode(1)=round(currentnode(1)+dx);
newnode(2)=round(currentnode(2)+dy);
    if out_bounds(newnode)==0
       if (map(newnode(1),newnode(2))==0)
       status=1;
       else
       newnode=[];
       status=0;
       end
   else
       newnode=[];
       status=0;
   end
  
end
% for the case with [rpm1 0]
function[status,newnode]= movemoreright(currentnode,rpm_vec,t,map)

th=currentnode(3);
dx=0;
dy=0;
    for k=1:100
        dx=dx+(2*pi*(r/120)*(rpm_vec(1))*cosd(th)*(t/100));
        dy=dy+(2*pi*(r/120)*(rpm_vec(1))*sind(th)*(t/100));
        dtheta=-6*(r/L)*(rpm_vec(1))*(t/100);
        th=th+dtheta;
        if out_bounds([currentnode(1)+dx,currentnode(2)+dy])==0
            if (map(round(currentnode(1)+dx),round(currentnode(2)+dy))==2)
                 status=0;
            end
        end
    end
newnode(3)=th;
newnode(1)=round(currentnode(1)+dx);
newnode(2)=round(currentnode(2)+dy);
    if out_bounds(newnode)==0
       if (map(newnode(1),newnode(2))==0)
       status=1;
       else
       newnode=[];
       status=0;
       end
   else
       newnode=[];
       status=0;
   end
end
% for [rpm2 0]
function[status,newnode]= movemoreright_l(currentnode,rpm_vec,t,map)

th=currentnode(3);
dx=0;
dy=0;
    for k=1:100
        dx=dx+(2*pi*(r/120)*(rpm_vec(2))*cosd(th)*(t/100));
        dy=dy+(2*pi*(r/120)*(rpm_vec(2))*sind(th)*(t/100));
        dtheta=-6*(r/L)*(rpm_vec(2))*(t/100);
        th=th+dtheta;
        if out_bounds([currentnode(1)+dx,currentnode(2)+dy])==0
            if (map(round(currentnode(1)+dx),round(currentnode(2)+dy))==2)
                 status=0;
            end
        end
    end
newnode(3)=th;
newnode(1)=round(currentnode(1)+dx);
newnode(2)=round(currentnode(2)+dy);
    if out_bounds(newnode)==0
       if (map(newnode(1),newnode(2))==0)
       status=1;
       else
       newnode=[];
       status=0;
       end
   else
       newnode=[];
       status=0;
   end
end

% for [rpm1 rpm2]
function[status,newnode]= movebit_left(currentnode,rpm_vec,t,map)

th=currentnode(3);
dx=0;
dy=0;
    for k=1:100
        dx=dx+(2*pi*(r/120)*(rpm_vec(2)+rpm_vec(1))*cosd(th)*(t/100));
        dy=dy+(2*pi*(r/120)*(rpm_vec(2)+rpm_vec(1))*sind(th)*(t/100));
        dtheta=6*(r/L)*(rpm_vec(2)-rpm_vec(1))*(t/100);
        th=th+dtheta;
        if out_bounds([currentnode(1)+dx,currentnode(2)+dy])==0
            if (map(round(currentnode(1)+dx),round(currentnode(2)+dy))==2)
                 status=0;
            end
        end
    end
newnode(3)=th;
newnode(1)=round(currentnode(1)+dx);
newnode(2)=round(currentnode(2)+dy);
    if out_bounds(newnode)==0
       if (map(newnode(1),newnode(2))==0)
       status=1;
       else
       newnode=[];
       status=0;
       end
   else
       newnode=[];
       status=0;
   end
end
%for [rpm2 rpm1]
function[status,newnode]= movebit_right(currentnode,rpm_vec,t,map)

th=currentnode(3);
dx=0;
dy=0;
    for k=1:100
        dx=dx+(2*pi*(r/120)*(rpm_vec(2)+rpm_vec(1))*cosd(th)*(t/100));
        dy=dy+(2*pi*(r/120)*(rpm_vec(2)+rpm_vec(1))*sind(th)*(t/100));
        dtheta=6*(r/L)*(rpm_vec(1)-rpm_vec(2))*(t/100);
        th=th+dtheta;
        if out_bounds([currentnode(1)+dx,currentnode(2)+dy])==0
            if (map(round(currentnode(1)+dx),round(currentnode(2)+dy))==2)
                 status=0;
            end
        end
    end
newnode(3)=th;
newnode(1)=round(currentnode(1)+dx);
newnode(2)=round(currentnode(2)+dy);
   if out_bounds(newnode)==0
       if (map(newnode(1),newnode(2))==0)
       status=1;
       else
       newnode=[];
       status=0;
       end
   else
       newnode=[];
       status=0;
   end
end
            
hold(app.UIAxes, 'on')
for i=1:size(path,1)
plot(app.UIAxes,path(i,1), path(i,2),'b*');
drawnow
pause(0.0001);
end
            %vis
%             hold(app.UIAxes, 'on')
%             for i=1:size(visited_a,1)
%             rectangle(app.UIAxes,'Position',[visited_a(i,1) visited_a(i,2) 1 1], 'FaceColor','yellow','EdgeColor','r');
%             pause(0.00001);
%             end

        end

        % Button pushed function: BugAlgorithmButton
        function BugAlgorithmButtonPushed(app, event)
app.UIAxes.cla;
clear all;
close all;
clc;

global  wL wR pose
global pposetrue ppose Oppose GGPS initPos goalPos
GPS=[];
Accel=[];
vrep=remApi('remoteApi');
vrep.simxFinish(-1);
clientID=vrep.simxStart('127.0.0.1',19997,true,true,5000,5);
vrep.simxStartSimulation(clientID,vrep.simx_opmode_oneshot);

if (clientID>-1)
    % If connection successful
    disp('Connected')
    % Create handles for required V-Rep objects
    [returnCode,left_Motor]=vrep.simxGetObjectHandle(clientID,'motor_left',vrep.simx_opmode_blocking);
    [returnCode,right_Motor]=vrep.simxGetObjectHandle(clientID,'motor_right',vrep.simx_opmode_blocking);
    [returnCode,front_Sensor]=vrep.simxGetObjectHandle(clientID,'front_prox',vrep.simx_opmode_blocking);
    [returnCode,front_right]=vrep.simxGetObjectHandle(clientID,'front_right',vrep.simx_opmode_blocking);
    [returnCode,rear_right]=vrep.simxGetObjectHandle(clientID,'rear_right',vrep.simx_opmode_blocking);
    [returnCode,front_left]=vrep.simxGetObjectHandle(clientID,'front_left',vrep.simx_opmode_blocking);
    [returnCode,rear_left]=vrep.simxGetObjectHandle(clientID,'rear_left',vrep.simx_opmode_blocking);
    [returnCode,init]=vrep.simxGetObjectHandle(clientID,'init',vrep.simx_opmode_blocking)
    [returnCode,goal]=vrep.simxGetObjectHandle(clientID,'goal',vrep.simx_opmode_blocking)
    [returnCode,Orobot]=vrep.simxGetObjectHandle(clientID,'Robotpose',vrep.simx_opmode_blocking)
    [returnCode,robot]=vrep.simxGetObjectHandle(clientID,'.',vrep.simx_opmode_blocking)
    % Initialise sensors
    [returnCode,detectionState,distanceFront,~,~]=vrep.simxReadProximitySensor(clientID,front_Sensor,vrep.simx_opmode_streaming);
    [returnCode,detectionState,dFR,~,~]=vrep.simxReadProximitySensor(clientID,front_right,vrep.simx_opmode_streaming);
    [returnCode,detectionState,dRR,~,~]=vrep.simxReadProximitySensor(clientID,rear_right,vrep.simx_opmode_streaming);
    [returnCode,detectionState,dFL,~,~]=vrep.simxReadProximitySensor(clientID,front_left,vrep.simx_opmode_streaming);
    [returnCode,detectionState,dRL,~,~]=vrep.simxReadProximitySensor(clientID,rear_left,vrep.simx_opmode_streaming);
    [returnCode,Orierobot]=vrep.simxGetObjectOrientation(clientID,Orobot,-1,vrep.simx_opmode_streaming)
    [returnCode,initPos]=vrep.simxGetObjectPosition(clientID,init,-1,vrep.simx_opmode_streaming)
    [returnCode,goalPos]=vrep.simxGetObjectPosition(clientID,goal,-1,vrep.simx_opmode_streaming)
    [returnCode,time]=vrep.simxGetFloatSignal(clientID,'SimulationTime',vrep.simx_opmode_streaming);
    [returnCode,GPS(1)]=vrep.simxGetFloatSignal(clientID,'GPS1',vrep.simx_opmode_streaming);
    [returnCode,GPS(2)]=vrep.simxGetFloatSignal(clientID,'GPS2',vrep.simx_opmode_streaming);
    [returnCode,GPS(3)]=vrep.simxGetFloatSignal(clientID,'GPS3',vrep.simx_opmode_streaming);
    [returnCode,Gyro]=vrep.simxGetFloatSignal(clientID,'Gyrodata',vrep.simx_opmode_streaming);
    [returnCode,Accel(1)]=vrep.simxGetFloatSignal(clientID,'Accel1',vrep.simx_opmode_streaming);
    [returnCode,Accel(2)]=vrep.simxGetFloatSignal(clientID,'Accel2',vrep.simx_opmode_streaming);
    [returnCode,Accel(3)]=vrep.simxGetFloatSignal(clientID,'Accel3',vrep.simx_opmode_streaming);
    [returnCode,Vel(1)]=vrep.simxGetFloatSignal(clientID,'Velocity1',vrep.simx_opmode_streaming);
    [returnCode,Vel(2)]=vrep.simxGetFloatSignal(clientID,'Velocity2',vrep.simx_opmode_streaming);
    [returnCode,Vel(3)]=vrep.simxGetFloatSignal(clientID,'Velocity3',vrep.simx_opmode_streaming);
    [returnCode,observed_Vel,]=vrep.simxGetObjectVelocity(clientID,Orobot,vrep.simx_opmode_streaming);
    [returnCode,observed_Vel,]=vrep.simxGetObjectVelocity(clientID,robot,vrep.simx_opmode_streaming);
    %Constructiveparameter
    orientation=[0,0,0];
    initPos=[1.025,1.925,0];
    goalPos=[-1.3,-2.025,0];
    position=initPos;
    wheel_radifront=0.03  %wheel radifront
    b=0.0823  %wheel base(wheel separation distance)
    a=0.03  %Separation distance between front and rear prox sensors
    vref=0.08  %velocity when following the wall moving forward
    vTurn=0.01 %velocity when turning
    v=0.1;
    objectDist=0.2  %distance to stop the robot when detecting an object
    min_distfront= objectDist  %Distance to stop when there's a wall in front of the robot
    kWall=100%Wall following gain
    e=0.5 %Distance of an off-center point toper form the kinematic control for wallfollowing
    wallDist=objectDist%-sepprox  %The expected distance to detect a wall with prox sensors
    dWallSide=0.3;
    goalDetectedTol=10*pi/180;  %Tolerance to point to the goal
    wallDetectedTol=0.5  %Tolerance to determine that there's a wall when rotating
    goalReachedTol=0.1   %Tolerance to determine the robot has reached the goal
    States={"Pointtogoal","Moveforward","Turn","Followwalllinenotcrossed","Followwall","Stop"}  %Namesforeachdefinedstateofthealgorithm
    state=1  %Initial state
    direction=1  %Follow right 1,follow left -1
    max_distfront=0.5  %Maximum distance returned by the front sensor
    d_front=max_distfront  %Initial front distance
    max_distprox=1  %Maximum distance returned by the prox sensors
    dFR=max_distprox  %Initial prox distance
    dRR=max_distprox  %Initial prox distance
    dFL=max_distprox  %Initial prox distance
    dRL=max_distprox  %Initial prox distance
    turnTime=0;
    checkGoal=false;
    i=1;
    Oppose=[]; %updatetoplot
    ppose=[];%updatetoplot
    pposetrue=[];%updatetoplot
    GGPS=[];%updatetoplot
    GPSs=[];%updatetoplot
    %
    sample = 100
    Accel_sample = zeros(sample,3);
    Pos_sample =zeros(sample,3);
    getSample = 1;
    Pos_sample(getSample,:)=initPos
    checkFull = false;
    delta_t = 0.05;
    counter=1;
    i=1;
    [returnCode]=vrep.simxSetJointTargetVelocity(clientID, left_Motor, 0 ,vrep.simx_opmode_blocking);
    [returnCode]=vrep.simxSetJointTargetVelocity(clientID, right_Motor, 0 ,vrep.simx_opmode_blocking);
    %Bug algorithm
    while ~checkGoal
    %Read proximity sensors
    [returnCode,detectionState,distanceFront,~,~]=vrep.simxReadProximitySensor(clientID,front_Sensor,vrep.simx_opmode_buffer);
    [returnCode,detectionState,dFR,~,~]=vrep.simxReadProximitySensor(clientID,front_right,vrep.simx_opmode_buffer);
    [returnCode,detectionState,dRR,~,~]=vrep.simxReadProximitySensor(clientID,rear_right,vrep.simx_opmode_buffer);
    [returnCode,detectionState,dFL,~,~]=vrep.simxReadProximitySensor(clientID,front_left,vrep.simx_opmode_buffer);
    [returnCode,detectionState,dRL,~,~]=vrep.simxReadProximitySensor(clientID,rear_left,vrep.simx_opmode_buffer);
     distanceFront=distanceFront(1,3);
     dFR=dFR(1,3);
     dRR=dRR(1,3);
     dFL=dFL(1,3);
     dRL=dRL(1,3);
     [returnCode,time]=vrep.simxGetFloatSignal(clientID,'SimulationTime',vrep.simx_opmode_buffer);
     [returnCode,GPS(1)]=vrep.simxGetFloatSignal(clientID,'GPS1',vrep.simx_opmode_buffer);
     [returnCode,GPS(2)]=vrep.simxGetFloatSignal(clientID,'GPS2',vrep.simx_opmode_buffer);
     [returnCode,GPS(3)]=vrep.simxGetFloatSignal(clientID,'GPS3',vrep.simx_opmode_buffer);
     [returnCode,Gyro]=vrep.simxGetFloatSignal(clientID,'Gyrodata',vrep.simx_opmode_buffer);
     [returnCode,Accel(1)]=vrep.simxGetFloatSignal(clientID,'Accel1',vrep.simx_opmode_buffer);
     [returnCode,Accel(2)]=vrep.simxGetFloatSignal(clientID,'Accel2',vrep.simx_opmode_buffer);
     [returnCode,Accel(3)]=vrep.simxGetFloatSignal(clientID,'Accel3',vrep.simx_opmode_buffer);
     [returnCode,Vel(1)]=vrep.simxGetFloatSignal(clientID,'Velocity1',vrep.simx_opmode_buffer);
     [returnCode,Vel(2)]=vrep.simxGetFloatSignal(clientID,'Velocity2',vrep.simx_opmode_buffer);
     [returnCode,Vel(3)]=vrep.simxGetFloatSignal(clientID,'Velocity3',vrep.simx_opmode_buffer);
     %Get Robotpose
    vrep.simxGetStringSignal(clientID,'Robotpose',vrep.simx_opmode_streaming);
    vrep.simxGetStringSignal(clientID,'init',vrep.simx_opmode_streaming);
    vrep.simxGetStringSignal(clientID,'goal',vrep.simx_opmode_streaming);
    [returnCode,datapose]=vrep.simxGetStringSignal(clientID,'Robotpose',vrep.simx_opmode_buffer);
    [returnCode,datainit]=vrep.simxGetStringSignal(clientID,'init',vrep.simx_opmode_buffer);
    [returnCode,datagoal]=vrep.simxGetStringSignal(clientID,'goal',vrep.simx_opmode_buffer);
    if (returnCode==vrep.simx_return_ok) % After initialization of streaming, it will take a few ms before the first value arrives, so check the return code
        position=vrep.simxUnpackFloats(datapose)
    end
    %[returnCode,position]=vrep.simxGetObjectPosition(clientID,robot,-1,vrep.simx_opmode_streaming);
    [returnCode,orientation]=vrep.simxGetObjectOrientation(clientID,Orobot,-1,vrep.simx_opmode_buffer);
    truepose =[position(1,1),position(1,2),orientation(1,3)];
    %init state
    if (checkFull == false)
        pause(0.05);
        Pos_sample(getSample,:)=GPS;
        Accel_sample(getSample,:)=Accel;
        getSample = getSample + 1
        if getSample > sample 
        checkFull = true
        [R,Accel_cov_mat] = getCovMat(Pos_sample,Accel_sample)
        init_state = GPS;
        init_state(4) = 0;
        init_state(5) = 0;
        init_state(6) = 0;
        prev_state = transpose(init_state);
        prev_P =[1,0,0,1,0,0,
                0,1,0,0,1,0,
                0,0,1,0,0,1,
                1,0,0,1,0,0,
                0,1,0,0,1,0,
                0,0,1,0,0,1];
        end
    elseif checkFull == true
        %Kaman predict
        A = [1,0,0,delta_t,0,0,
            0,1,0,0,delta_t,0,
            0,0,1,0,0,delta_t,
            0,0,0,1,0,0,
            0,0,0,0,1,0,
            0,0,0,0,0,1]
        B = [(1/2)*(delta_t^2),0,0,
            0,(1/2)*(delta_t^2),0,
            0,0,(1/2)*(delta_t^2),
            delta_t,0,0,
            0,delta_t,0,
            0,0,delta_t]
        Q = [(1/4)*(delta_t^4),0,0,(1/2)*(delta_t^3),0,0,
            0,(1/4)*(delta_t^4),0,0,(1/2)*(delta_t^3),0,
            0,0,(1/4)*(delta_t^4),0,0,(1/2)*(delta_t^3),
            (1/2)*(delta_t^3),0,0,delta_t,0,0,
            0,(1/2)*(delta_t^3),0,0,delta_t,0,
            0,0,(1/2)*(delta_t^3),0,0,delta_t]*(Accel_cov_mat)
        [returnCode,Accel(1)]=vrep.simxGetFloatSignal(clientID,'Accel1',vrep.simx_opmode_buffer);
        [returnCode,Accel(2)]=vrep.simxGetFloatSignal(clientID,'Accel2',vrep.simx_opmode_buffer);
        [returnCode,Accel(3)]=vrep.simxGetFloatSignal(clientID,'Accel3',vrep.simx_opmode_buffer);   
        control_vec = transpose(Accel);
        xk = A*(prev_state)+B*control_vec;
        [returnCode,orientation]=vrep.simxGetObjectOrientation(clientID,Orobot,-1,vrep.simx_opmode_buffer);
       % pose =[position(1,1),position(1,2),orientation(1,3)];
        pose =[xk(1),xk(2),orientation(1,3)];
        P = A*(prev_P)*transpose(A)+(Q)
        P(1,2)=0;
        P(1,3)=0;
        P(1,5)=0;
        P(1,6)=0;
        P(2,1)=0;
        P(2,3)=0;
        P(2,4)=0;
        P(2,6)=0;
        P(3,1)=0;
        P(3,2)=0;
        P(3,4)=0;
        P(3,5)=0;
        P(4,2)=0;
        P(4,3)=0;
        P(4,5)=0;
        P(4,6)=0;
        P(5,1)=0;
        P(5,3)=0;
        P(5,4)=0;
        P(5,6)=0;
        P(6,1)=0;
        P(6,2)=0;
        P(6,4)=0;
        P(6,5)=0;
    %State machine
    if(state==1)
    %TODO:Check if it is pointing to goal
    [pointing,direction] = PointingToGoal(pose,initPos,goalPos,goalDetectedTol);
        if pointing == true
        state=2
        end
    elseif (state==2)
    %TODO:Check if it is pointing to goal
    %TODO:Check if goal has been reached
    %TODO:Check if there's an object in front
    [pointing,direction] = PointingToGoal(pose,initPos,goalPos,goalDetectedTol);
    checkGoal=GoalReached(pose,goalPos,goalReachedTol);
    [returnCode,detectionState,distanceFront,~,~]=vrep.simxReadProximitySensor(clientID,front_Sensor,vrep.simx_opmode_buffer);
    distanceFront=distanceFront(1,3);    
        if checkGoal==true
            state=6;
        elseif pointing==false
            state=1;
        elseif  (distanceFront > 0.01) && (distanceFront <= min_distfront)
            direction=selectRandomDirection()
            %direction=1;
            state=3
        end
    elseif(state==3)
    %TODO:Check wall on the side
    [pointing,direction] = PointingToGoal(pose,initPos,goalPos,goalDetectedTol);
    [front,rear] = wallDetected(direction,dFL,dRL,dFR,dRR,dWallSide,wallDetectedTol);
        if (front ==true) || (rear == true)
            state=4
        end
    elseif(state==4)
    %TODO:Check side of theline
    [pointing,direction] = PointingToGoal(pose,initPos,goalPos,goalDetectedTol);    
    lineSide= LineSide(pose,initPos,goalPos);
        if lineSide==direction
            state=5
        end
    elseif(state==5)
    %TODO:Check side of theline
    lineSide= LineSide(pose,initPos,goalPos);
        if lineSide~=direction
            state=1
        end
    end
    [wL,wR,turnTime]=checkstate(state,a,kWall,vTurn,vref,wheel_radifront,direction,b,dFL,dRL,dFR,dRR,wallDist,e);
    [returnCode]=vrep.simxSetJointTargetVelocity(clientID, left_Motor, wL ,vrep.simx_opmode_blocking);
    [returnCode]=vrep.simxSetJointTargetVelocity(clientID, right_Motor, wR ,vrep.simx_opmode_blocking);
    %pause(turnTime);
    %Odometry
    SL= wL* delta_t*wheel_radifront;
    SR= wR*delta_t*wheel_radifront;
    deltaS=(SL+SR)/2;
    deltatheta=(SR-SL)/(2*b)
    x=truepose(1)+deltaS*cos(truepose(3));
    y=truepose(2)+deltaS*sin(truepose(3));
    theta=truepose(3)+ deltatheta;
    Odometrypose=[x,y,theta];
    %update to plot
    Oppose=[Odometrypose;Oppose]; 
    ppose=[pose ;ppose];
    pposetrue=[truepose;pposetrue];
    GPSs=[GPS(1),GPS(2)];
    GGPS=[GPSs;GGPS];

 %Kaman update
    H = eye(6);
    [returnCode,GPS(1)]=vrep.simxGetFloatSignal(clientID,'GPS1',vrep.simx_opmode_streaming);
    [returnCode,GPS(2)]=vrep.simxGetFloatSignal(clientID,'GPS2',vrep.simx_opmode_streaming);
    [returnCode,GPS(3)]=vrep.simxGetFloatSignal(clientID,'GPS3',vrep.simx_opmode_streaming);
    observed = [GPS(1),GPS(2),GPS(3)];
%    [returnCode,observed_Vel,~]=vrep.simxGetObjectVelocity(clientID,Orobot,vrep.simx_opmode_buffer);
    [returnCode,Vel(1)]=vrep.simxGetFloatSignal(clientID,'Velocity1',vrep.simx_opmode_buffer);
    [returnCode,Vel(2)]=vrep.simxGetFloatSignal(clientID,'Velocity2',vrep.simx_opmode_buffer);
    [returnCode,Vel(3)]=vrep.simxGetFloatSignal(clientID,'Velocity3',vrep.simx_opmode_buffer);    
    observed(4) = Vel(1);
    observed(5) = Vel(2);
    observed(6) = Vel(3);
    z = transpose(observed);
    y = z-H*xk;
    K = P*transpose(H)/(H*P*transpose(H)+R);         
    prev_state = xk+K*y
    prev_P = (eye(6)-K*H)*P;
    end
    end
    [returnCode]=vrep.simxSetJointTargetVelocity(clientID, left_Motor, 0 ,vrep.simx_opmode_blocking);
    [returnCode]=vrep.simxSetJointTargetVelocity(clientID, right_Motor, 0 ,vrep.simx_opmode_blocking);
    [returnCode]=vrep.simxStopSimulation(clientID,vrep.simx_opmode_oneshot_wait)

     % Stop the simulation
    vrep.simxFinish(clientID);
 
else
    % Connection Failed
    disp('Failed connecting to remote API server')
end
 
function [wL,wR,t]=checkstate(state,a,kWall,vTurn,vref,wheel_radifront,direction,b,dFL,dRL,dFR,dRR,wallDist,e)
    if(state==1)
        [wTurnL,wTurnR,turnTime]=precomputeTurn(vTurn,direction,wheel_radifront,b)
        wL=wTurnL;
        wR=wTurnR;
        t=turnTime;
    elseif(state==2)
        wL=vref/wheel_radifront;
        wR=vref/wheel_radifront;
        t=0;
    elseif(state==3)
        [wTurnL,wTurnR,turnTime]=precomputeTurn(vTurn,direction,wheel_radifront,b);
        wL=wTurnL;
        wR=wTurnR;
        t=turnTime;
    elseif(state==4)
        [wL,wR]=followWall(vref,a,direction,dFL,dRL,dFR,dRR,wallDist,kWall,wheel_radifront,b,e);
        t=0;
    elseif(state==5)
        [wL,wR]=followWall(vref,a,direction,dFL,dRL,dFR,dRR,wallDist,kWall,wheel_radifront,b,e);
        t=0;
    elseif(state==6)
        wL=0
        wR=0
        t=0;
    end
end

function [pointing,direction] = PointingToGoal(pose,initPos,goalPos,tolerance)
    %pose:list with x,y,theta values of therobot
    %initPose:list with x,y of the initial position
    %goalPose:list with x,y of the goal position
    %tolerance:admissible angle(in radians)
    %Returns true or false and the turning direction(1 or -1)
    angle_diff=(atan2(goalPos(1,2)-initPos(1,2),goalPos(1,1)-initPos(1,1))-pose(1,3));
    if(abs(angle_diff)<tolerance)
        pointing=true
    else
        pointing=false
    end
    if(angle_diff>0)
        direction= +1
    else
        direction=-1
    end
end

function [wL,wR,t]=precomputeTurn(vturn,direction,wheel_radifront,b)
    %vturn:turning speed
    %direction:1->Follow right,-1->Follow left
    %wheel_radifront,b:constructive parameters
    %Returns wheels velocities and turningtime
    wL=-direction*vturn/wheel_radifront
    wR=direction*vturn/wheel_radifront;
    t=b*(pi/2)/vturn
end

function [front,rear] = wallDetected(direction,dFL,dRL,dFR,dRR,dWallSide,tolerance)
    %direction:1 wall on the right,-1 wall on the left
    %dFL,dRL:distance of prox sensors on the left
    %dFR,dRR:distance of prox sensors on the right
    %dWallSide:expected separation distance of the wall
    %tolerance:admissible distance to consider that there's a wall
    %Returns true or false
    if(direction==-1)
    dF=dFR
    dR=dRR
    else
    dF=dFL
    dR=dRL
    end
    dfront=abs(dF-dWallSide)
    drear=abs(dR-dWallSide)
    if dfront <tolerance
        front = true
    else front = false
    end
    if drear < tolerance
        rear = true;
    else rear =false
    end
end

function [wL,wR]= followWall(v,a,direction,dFL,dRL,dFR,dRR,dWallSide,kWall,wheel_radifront,b,e)
    %dFL,dRL:distance of prox sensors on the left
    %dFR,dRR:distance of prox sensors on the right
    %dWallSide:expected separation distance of the wall
    %wheel_radifront,a,b:Constructuve parameters
    %v,kWall,e:Parameters for wallfollowing algorithm
    %Returns wheels velocities
    if(direction==1)  %follow right wall
    phi=atan((dFR-dRR)/a)
    d=(0.5*(dFR+dRR)-dWallSide)
    else  %follow left wall
    phi=atan((dFL-dRL)/a)
    d=(dWallSide-0.5*(dFL+dRL))
    end
    gamma=kWall*d
    alpha=phi+gamma
    wL=(v/wheel_radifront)*(cos(alpha)+(b/e)*sin(alpha));
    wR=(v/wheel_radifront)*(cos(alpha)-(b/e)*sin(alpha))
end

function  [lineSide]= LineSide(pose,initPos,goalPos)
    %pose:list with x,y,theta values of the robot
    %initPose:list with x,y of the initial position
    %goalPose:listwith x,y of the goal position
    %Returns 1 on the left side and -1 on the right side
    xDiff1=initPos(1,1)-goalPos(1,1)
    yDiff1=goalPos(1,2)-initPos(1,2)
    xDiff2=initPos(1,1)-pose(1,1)
    yDiff2=initPos(1,2)-pose(1,2)
    dGoalIni=sqrt(xDiff1^2+yDiff1^2)
    d=(xDiff2*yDiff1+yDiff2*xDiff1)/dGoalIni
    if d>0
        lineSide = 1
    else
        lineSide = -1
    end
end

function checkGoal=GoalReached(pose,goalPos,tolerance)
    %pose:list with x,y,theta values of the robot
    %goalPose:list with x,y of the goal position
    %tolerance:admissible distance to consider that the goal has been reached
    %Returns true or false
    dGoal=sqrt((goalPos(1,1)-pose(1,1))^2+(goalPos(1,2)-pose(1,2))^2);
    if dGoal<tolerance
        checkGoal =true;
    else checkGoal =false
    end
end
function [direction] = selectRandomDirection()
    n=random('Normal',0,1);
    if(n<0.5)
    direction=1;
    else
    direction=-1;
    end
end
function [R,Accel_cov_mat]= getCovMat(Pos_sample,Accel_sample)
     Pos_deviation = Pos_sample - ones(length(Pos_sample),length(Pos_sample))*(Pos_sample)*(1/length(Pos_sample))
     Pos_cov = transpose(Pos_deviation)*(Pos_deviation)
     Accel_deviation = Accel_sample - ones(length(Accel_sample),length(Accel_sample))*(Accel_sample)*(1/length(Accel_sample))
     Accel_cov = transpose(Accel_deviation)*(Accel_deviation)
     R = diag([Pos_cov(1,1),Pos_cov(2,2),Pos_cov(3,3),0.1,0.1,0.1])
     Accel_cov_mat = diag([Accel_cov(1,1),Accel_cov(2,2),Accel_cov(3,3),Accel_cov(1,1),Accel_cov(2,2),Accel_cov(3,3)]) 
end

        end

        % Button pushed function: PlotTrajectoryButtonAstar
        function PlotTrajectoryButtonAstarPushed(app, event)
    close all;
        app.UIAxes.cla;
    global ppose Oppose 
    plot(app.UIAxes,ppose(:,1),ppose(:,2))
    hold(app.UIAxes, 'on') 
    plot(app.UIAxes,Oppose(:,1),Oppose(:,2))
    hold(app.UIAxes, 'on')  
    xlim(app.UIAxes,[0 10])
    ylim(app.UIAxes,[0 10])
    xticks(app.UIAxes,0:1:10);
     yticks(app.UIAxes,0:1:10);
    legend(app.UIAxes,'truepos','Odematry','FontSize',12);
    
    figure()
plot(ppose(:,1)-Oppose(:,1))
hold on
xlabel('Time step')
ylabel('Error x (m)')
title('Error in X')
ylim([-0.1 0.1]);
drawnow

figure()
plot(ppose(:,2)-Oppose(:,2))
hold on
xlabel('Time step')
ylabel('Error y (m)')
ylim([-0.1 0.1]);
title('Error in Y')
drawnow

        end

        % Button pushed function: CreateMapButton
        function CreateMapButtonPushed(app, event)
        %obs
      app.UIAxes.cla
      legend(app.UIAxes,'off')
            res=10;
    global map
    map=zeros(floor(10100/res),floor(11100/res));
    % axis([0,floor(11100/res),0,floor(10100/res)])
    for k=1:floor(10100/res)
        for l=1:floor(11100/res)
            if(obspace(k,l,res)==1)
                map(k,l)=2;
            else
                map(k,l)=0;
            end
        end
    end
    map=rot90(map,-1);

    Visualize(map)
    function Visualize(map)
    res = 1;
    cmap = [1 1 1; ...
            0 0 0; ...
            1 0 0; ...
            0 0 1; ...
            0 1 0; ...
            1 1 0; ...
        0.5 0.5 0.5];
    colormap(app.UIAxes,cmap);
    map = map';
    image(app.UIAxes,[0 1110],[0 1010],map); 
    set(app.UIAxes,'ydir','normal')
    hold(app.UIAxes,'on') ;
    %grid on
%    ydir(app.UIAxes,'normal')
%      set(gca,'ydir','normal'); 
%      set(gca,'XTick',0:50:1110,'YTick',0:50:1010);
     xticks(app.UIAxes,0:50:1110);
     yticks(app.UIAxes,0:50:1010);
     xlim(app.UIAxes,[0 1110]);
     ylim(app.UIAxes,[0 1010]);
     
    end
    function Cobs= obspace(x,y,res)
    % set clearance here
    global clearance
    clearance=210;
    % circle pillars(taking robot radius plus clearance as 200mm)
    c1=(x-floor(450/res))^2 + (y-floor(3900/res))^2 -(605/res)^2;
    c2=(x-floor(2740/res))^2 + (y-floor(4380/res))^2 -(605/res)^2;
    c3=(x-floor(7360/res))^2 + (y-floor(4380/res))^2 -(605/res)^2;
    c4=(x-floor(9650/res))^2 + (y-floor(3900/res))^2 -(605/res)^2;
    %tables
    % t1
    h1=-y+((8320-clearance)/res);
    h2=x-((1830+clearance)/res);
    h3=y-((9180+clearance)/res);
    h4=-x;
    if(h1<=0 && h2<=0 && h3<=0 && h4<=0)
        t1=1;
    else
        t1=0;
    end
    % t2
    h5=-y+((9830-clearance)/res);
    h6=x-((910+clearance)/res);
    h7=y-((10260+clearance)/res);
    h8=-x;
    if(h5<=0 && h6<=0 && h7<=0 && h8<=0)
        t2=1;
    else
        t2=0;
    end
    % t3
    h9=-y+((7440-clearance)/res);
    h10=x-((3890+clearance)/res);
    h11=y-(11100/res);
    h12=-x+((3130-clearance)/res);
    if(h9<=0 && h10<=0 && h11<=0 && h12<=0)
        t3=1;
    else
        t3=0;
    end
    % t4
    h13= -y+((10520-clearance)/res);
    h14= x-(5610/res);
    h15= y-(11100/res);
    h16= -x+((4445-clearance)/res);
    if(h13<=0 && h14<=0 && h15<=0 && h16<=0)
        t4=1;
    else
        t4=0;
    end

    % t5
    h17= -y+((10190-clearance)/res);
    h18= x-((6475+clearance)/res);
    h19=y-(11100/res);
    h20=-x+(5610/res);
    if(h17<=0 && h18<=0 && h19<=0 && h20<=0)
        t5=1;
    else
        t5=0;
    end
    % t6
    h21= -y+((10520-clearance)/res);
    h22= x-((8317+clearance)/res);
    h23=y-(11100/res);
    h24=-x+((7147-clearance)/res);
    if(h21<=0 && h22<=0 && h23<=0 && h24<=0)
        t6=1;
    else
        t6=0;
    end

    % t7
    h25=-y+((9270-clearance)/res);
    h26=x-((9750+clearance)/res);
    h27=y-(11100/res);
    h28=-x+((8990-clearance)/res);
    if(h25<=0 && h26<=0 && h27<=0 && h28<=0)
        t7=1;
    else
        t7=0;
    end
    % t8
    h29=-y+((7790-clearance)/res);
    h30=x-(9750/res);
    h31=y-((8960+clearance)/res);
    h32=-x+((9170-clearance)/res);
    if(h29<=0 && h30<=0 && h31<=0 && h32<=0)
        t8=1;
    else
        t8=0;
    end
    % t9
    h33=-y+((4740-clearance)/res);
    h34=x-(9750+clearance/res);
    h35=y-((7480+clearance)/res);
    h36=-x+((8230-clearance)/res);
    if(h33<=0 && h34<=0 && h35<=0 && h36<=0)
        t9=1;
    else
        t9=0;
    end
    % t10
    h37=-y+((6850-clearance)/res);
    h38=x-((10100)/res);
    h39=y-(11100/res);
    h40=-x+(9750/res);
    if(h37<=0 && h38<=0 && h39<=0 && h40<=0)
        t10=1;
    else
        t10=0;
    end
    % t11
    h41=-y+((7845-clearance)/res);
    h42=x-((7430+clearance)/res);
    h43=y-((9365+clearance)/res);
    h44=-x+((6260-clearance)/res);
    if(h41<=0 && h42<=0 && h43<=0 && h44<=0)
        t11=1;
    else
        t11=0;
    end
    % t12
    h45=-y+((5290-clearance)/res);
    h46=x-((7450+clearance)/res);
    h47=y-((7120+clearance)/res);
    h48=-x+((6690-clearance)/res);
    if(h45<=0 && h46<=0 && h47<=0 && h48<=0)
        t12=1;
    else
        t12=0;
    end
    % t13
    h49=-y+((4380-clearance)/res);
    h50=x-((6950+clearance)/res);
    h51=y-((5290+clearance)/res);
    h52=-x+((5120-clearance)/res);
    if(h49<=0 && h50<=0 && h51<=0 && h52<=0)
        t13=1;
    else
        t13=0;
    end
    % elliptical table
    e1=(x-floor(1799/res))^2 + (y-floor(1500/res))^2 -(1000/res)^2;
    e2=x-((2599+clearance)/res);
    e3=(x-floor(1799/res))^2 + (y-floor(3097/res))^2 -(1000/res)^2;
    e4=-x+((1000-clearance)/res);
    e5=-y+((1499-clearance)/res);
    e6=y-((3097+clearance)/res);
    if(e1<=0||e3<=0||(e5<=0&&e6<=0&&e2<=0&&e4<=0))
        et=1;
    else
        et=0;
    end
    % boundaries of map
    h53=y-(clearance/res);
    h54=-y;
    h55=-x+((10100-clearance)/res);
    h56=x-(10100/res);
    h57=y-(11100/res);
    h58=-y+((11100-clearance)/res);
    h59=x-(clearance/res);
    if((h53<=0 && h54<=0)||(h55<=0&&h56<=0)||(h57<=0&&h58<=0)||h59<=0)
        t14=1;
    else
        t14=0;
    end

    if(c1<=0||c2<=0||c3<=0||c4<=0||t1==1||t2==1||t3==1||t4==1||t5==1||t6==1||t7==1||t8==1||t9==1||t10==1||t11==1||t12==1||t13==1||et==1||t14==1)
        Cobs=1;
    else
        Cobs=0;
    end
    end
        end

        % Button pushed function: PlotTrajectoryButtonBug
        function PlotTrajectoryButtonBugPushed(app, event)
        global pposetrue ppose Oppose GGPS initPos goalPos
        app.UIAxes.cla
        plot(app.UIAxes,pposetrue(:,1),pposetrue(:,2))
        hold(app.UIAxes, 'on')
        plot(app.UIAxes,ppose(:,1),ppose(:,2))
        hold(app.UIAxes, 'on')
        plot(app.UIAxes,Oppose(:,1),Oppose(:,2))
        hold(app.UIAxes, 'on') 
        plot(app.UIAxes,GGPS(:,1),GGPS(:,2))
        hold(app.UIAxes, 'on')
        plot(app.UIAxes,initPos(1),initPos(2),'s','color','green','markers',10)
        hold(app.UIAxes, 'on')
        plot(app.UIAxes,goalPos(1),goalPos(2),'s','color','red','markers',10)
        txt1 = '\leftarrow Init';
        txt2 = '\leftarrow Goal';
        text(app.UIAxes,initPos(1),initPos(2),txt1)
        text(app.UIAxes,goalPos(1),goalPos(2),txt2)
        xlim(app.UIAxes,[-1.5 1.5])
        ylim(app.UIAxes,[-2.5 2])
        legend(app.UIAxes,'truepos','Kaman','Odematry','GPS','init','goal');
        drawnow
        
        % true kaman gps
figure()
plot(pposetrue(:,1),pposetrue(:,2))
hold on
plot(ppose(:,1),ppose(:,2))
hold on
plot(GGPS(:,1),GGPS(:,2))
hold on
plot(initPos(1),initPos(2),'s','color','green','markers',10)
hold on
plot(goalPos(1),goalPos(2),'s','color','red','markers',10)
txt1 = '\leftarrow Init';
txt2 = '\leftarrow Goal';
text(initPos(1),initPos(2),txt1)
text(goalPos(1),goalPos(2),txt2)
xlabel('x (m)')
ylabel('y (m)')
legend('truepos','Kaman','GPS','init','goal','FontSize',12);
title('Robot Trajectory')

% true odemetry
figure()
plot(pposetrue(:,1),pposetrue(:,2))
hold on
plot(Oppose(:,1),Oppose(:,2))
hold on 
plot(initPos(1),initPos(2),'s','color','green','markers',10)
hold on
plot(goalPos(1),goalPos(2),'s','color','red','markers',10)
txt1 = '\leftarrow Init';
txt2 = '\leftarrow Goal';
text(initPos(1),initPos(2),txt1)
text(goalPos(1),goalPos(2),txt2)
xlabel('x (m)')
ylabel('y (m)')
legend('truepos','Odemetry','init','goal','FontSize',12);
title('Robot Trajectory')

figure()
plot(pposetrue(:,1));
hold on
plot(ppose(:,1))
hold on
plot(GGPS(:,1))
hold on
legend('truepos','Kaman','GPS','FontSize',12)
drawnow
xlabel('Time step')
ylabel('x (m)')
title('X')
grid on

figure()
plot(pposetrue(:,2));
hold on
plot(ppose(:,2))
hold on
plot(GGPS(:,2))
hold on
legend('truepos','Kaman','GPS','FontSize',12)
title('Y')
xlabel('Time step')
ylabel('y (m)')
grid on

figure()
plot(pposetrue(:,1));
hold on
plot(Oppose(:,1))
hold on
legend('truepos','Odemetry')
drawnow
xlabel('Time step')
ylabel('x (m)')
title('X')
grid on

figure()
plot(pposetrue(:,2));
hold on
plot(Oppose(:,2))
hold on
legend('truepos','Odemetry')
drawnow
xlabel('Time step')
ylabel('y (m)')
title('Y')
grid on

%true kaman in X
figure()
plot(pposetrue(:,1)-ppose(:,1))
hold on
%legend('truepos','Kaman')
title('Error in X True and Kaman')
xlabel('Time step')
ylabel('Error x (m)')

%true kaman in Y
figure()
plot(pposetrue(:,2)-ppose(:,2))
hold on
%legend('truepos','Kaman')

title('Error in Y True and Kaman')
xlabel('Time step')
ylabel('Error y (m)')

% True Odemetry in X
figure()
plot(pposetrue(:,1)-Oppose(:,1))
hold on
%legend('truepos','Odemetry')
ylim([-0.1 0.1]);
title('Error in X True and Odemetry')
xlabel('Time step')
ylabel('Error x (m)')

% True Odemetry in Y
figure()
%grid on;
plot(pposetrue(:,2)-Oppose(:,2))
hold on
%legend('truepos','Odemetry')
xlabel('Time step')
ylabel('Error y (m)')
ylim([-0.1 0.1]);
title('Error in Y True and Odemetry')


        end

        % Button pushed function: RunKinematicButton
        function RunKinematicButtonPushed(app, event)
global robPose ppose
% Sampling time
Tstep = 0.1;
wheel_radifront=0.03;
vrep=remApi('remoteApi');
vrep.simxFinish(-1); 
clientID=vrep.simxStart('127.0.0.1',19997,true,true,5000,5);
vrep.simxStartSimulation(clientID,vrep.simx_opmode_oneshot);

ppose=[0 0 0];
position=[0 0 0];
t=5;
% Start pose
x0 = 0;
y0 = 0;
theta0 = 0;
x0_sim=[0,x0];
y0_sim=[0,y0];
theta0_sim=[0,theta0];
 if (clientID>-1)
    disp('Connected');
    [returnCode,Robot]=vrep.simxGetObjectHandle(clientID,'Robot',vrep.simx_opmode_blocking );
    [returnCode,left_Motor]=vrep.simxGetObjectHandle(clientID,'motor_left',vrep.simx_opmode_blocking );
    [returnCode,right_Motor]=vrep.simxGetObjectHandle(clientID,'motor_right',vrep.simx_opmode_blocking );
    [returnCode,time]=vrep.simxGetFloatSignal(clientID,'SimulationTime',vrep.simx_opmode_streaming);
    [returnCode,position]=vrep.simxGetObjectPosition(clientID,Robot,-1,vrep.simx_opmode_streaming)
    run = true;
    [returnCode,position]=vrep.simxGetObjectPosition(clientID,Robot,-1,vrep.simx_opmode_buffer)
    %pause(0.05);
    while run
    [returnCode,time]=vrep.simxGetFloatSignal(clientID,'SimulationTime',vrep.simx_opmode_buffer);
    if time>t &&time <=2+t
    [returnCode,position]=vrep.simxGetObjectPosition(clientID,Robot,-1,vrep.simx_opmode_buffer)
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,left_Motor,0.1/wheel_radifront,vrep.simx_opmode_blocking);    
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,right_Motor,0.1/wheel_radifront,vrep.simx_opmode_blocking);
    ppose=[ppose;position];
    
    %pause(0.05)
    elseif time>2+t && time<=5+t
     [returnCode,position]=vrep.simxGetObjectPosition(clientID,Robot,-1,vrep.simx_opmode_buffer)
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,left_Motor,0.05/wheel_radifront,vrep.simx_opmode_blocking);    
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,right_Motor,0.1/wheel_radifront,vrep.simx_opmode_blocking);
    ppose=[ppose;position];
    
   % pause(0.05)
 
    elseif time >5+t&& time <9+t
     [returnCode,position]=vrep.simxGetObjectPosition(clientID,Robot,-1,vrep.simx_opmode_buffer)
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,left_Motor,0.1/wheel_radifront,vrep.simx_opmode_blocking);    
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,right_Motor,0.05/wheel_radifront,vrep.simx_opmode_blocking);
    ppose=[ppose;position];
    
   % pause(0.05)
    elseif time>=9+t
    run= false;
    [returnCode]=vrep.simxSetJointTargetVelocity(clientID, left_Motor, 0 ,vrep.simx_opmode_blocking);
    [returnCode]=vrep.simxSetJointTargetVelocity(clientID, right_Motor, 0 ,vrep.simx_opmode_blocking);
    end
    end
    %[returnCode]=vrep.simxStopSimulation(clientID,vrep.simx_opmode_oneshot)
    % Run the simulation
    sim('Testkinematic');   
    %Plot results
%     figure()
%     plot(ppose(:,1),ppose(:,2));
%     hold on
%     plot(robPose(:,1),robPose(:,2));
%     hold on
%     legend('Truepos','Kinematic','FontSize',12);
%     title('Robot trajectory')
%     xlabel('x (m)')
%     ylabel('y (m)')
%     grid on

    
     % Stop the simulation
     vrep.simxStopSimulation(clientID,vrep.simx_opmode_oneshot_wait);
    vrep.simxFinish(clientID);
 
else
    % Connection Failed
    disp('Failed connecting to remote API server')
end


        end

        % Button pushed function: PlotTrajectoryButton
        function PlotTrajectoryButtonPushed(app, event)
        
        global robPose ppose
        app.UIAxes.cla
        %sim('Testkinematic');  
        plot(app.UIAxes,robPose(:,1),robPose(:,2));
        hold(app.UIAxes, 'on')
        plot(app.UIAxes,ppose(:,1),ppose(:,2));
        hold(app.UIAxes, 'on')
        xlim(app.UIAxes,[-0.1 1.2]);
        ylim(app.UIAxes,[-0.1 1]);
        legend(app.UIAxes,'Truepos','Kinematic','Location','southeast','FontSize',12);
        hold(app.UIAxes, 'on')
        
            figure()
    plot(ppose(:,1)-robPose(:,1))
    title('Error in X')
    xlabel('Time step')
    ylabel('Error x(m)')
    grid on
    figure()
    plot(ppose(:,2)-robPose(:,2))
    title('Error in Y')
    xlabel('Time step')
    ylabel('Error y(m)')
    grid on
       
        end

        % Button pushed function: RunInverseKinematicButton
        function RunInverseKinematicButtonPushed(app, event)
            %Testinvkinematiccontrol
            app.UIAxes.cla
            close all;
global robPose ppose
% Sampling time
dt = 0.1;
wheel_radifront=0.03;
vrep=remApi('remoteApi');
vrep.simxFinish(-1); 
clientID=vrep.simxStart('127.0.0.1',19997,true,true,5000,5);
vrep.simxStartSimulation(clientID,vrep.simx_opmode_oneshot);
ppose=[0 0 0];
position=[0 0 0];
% Start pose
xk=0; %for caculating kinematic
yk=0; %for caculating kinematic
thetak=0; %for caculating kinematic

% Robot parameter: Distance between two wheels
d=0.0823;
% End pose
% target.time=0;
% target.signals.values=[1 1 pi/2];
goal=[1 1 pi/2];
% Control parameters
gamma=0.3;
lamda=0.6;
h=0.1;
endgoal=false

robPose=[0 0 0]
 if (clientID>-1)
    disp('Connected');
    [returnCode,Robot]=vrep.simxGetObjectHandle(clientID,'Robot',vrep.simx_opmode_blocking );
    [returnCode,left_Motor]=vrep.simxGetObjectHandle(clientID,'motor_left',vrep.simx_opmode_blocking );
    [returnCode,right_Motor]=vrep.simxGetObjectHandle(clientID,'motor_right',vrep.simx_opmode_blocking );
    [returnCode,position]=vrep.simxGetObjectPosition(clientID,Robot,-1,vrep.simx_opmode_streaming)
    [returnCode,Orobot]=vrep.simxGetObjectHandle(clientID,'Robotpose',vrep.simx_opmode_blocking)
    [returnCode,orientation]=vrep.simxGetObjectOrientation(clientID,Orobot,-1,vrep.simx_opmode_streaming);

    while endgoal==false
    [returnCode,position]=vrep.simxGetObjectPosition(clientID,Robot,-1,vrep.simx_opmode_buffer)
    [returnCode,orientation]=vrep.simxGetObjectOrientation(clientID,Orobot,-1,vrep.simx_opmode_buffer);
    x=position(1);
    y=position(2);
    theta1= orientation(3);  
    % Calculate polar variables
    rho = sqrt((goal(1)-x)^2+(goal(2)-y)^2);
    phi = atan2(goal(2)-y,goal(1)-x)-goal(3);
    alpha = phi+goal(3)-theta1;
    % Calculate control laws
    v = gamma*cos(alpha)*rho;
    w = lamda*alpha + gamma*cos(alpha)*sin(alpha)/alpha*(alpha+h*phi);
    % Calculate Vl and Vr from v and w
    vl = (2*v-d*w)/2;
    vr = (2*v+d*w)/2;
    xk= xk+(vr+vl)*cos(thetak)*dt/2 ;
    yk= yk+(vr+vl)*sin(thetak)*dt/2 ;
    thetak=thetak +(vr-vl)*dt/(2*d);
   
    robPose=[robPose;[xk,yk,thetak]];
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,left_Motor,vl/wheel_radifront,vrep.simx_opmode_blocking);    
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,right_Motor,vr/wheel_radifront,vrep.simx_opmode_blocking);
    ppose=[ppose;position];
    if sqrt((goal(1)-x)^2+(goal(2)-y)^2)<0.15&& theta1-goal(3)<0.01
        endgoal =true;
    end
    end
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,left_Motor,0,vrep.simx_opmode_blocking);    
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,right_Motor,0,vrep.simx_opmode_blocking);
    [returnCode]=vrep.simxStopSimulation(clientID,vrep.simx_opmode_oneshot_wait)
     % Stop the simulation
    vrep.simxFinish(clientID);
    
else
    % Connection Failed
    disp('Failed connecting to remote API server')
end
        end

        % Button pushed function: INSButton
        function INSButtonPushed(app, event)
            %INSKalman
app.UIAxes.cla
global pposetrue ppose GGPS
vrep=remApi('remoteApi');
vrep.simxFinish(-1); 
clientID=vrep.simxStart('127.0.0.1',19997,true,true,5000,5);
%vrep.simxSynchronous(clientID,true);
%dt=0.05;
%vrep.simxSetFloatingParameter(clientID,vrep.sim_floatparam_simulation_time_step,dt,vrep.simx_opmode_blocking);
vrep.simxStartSimulation(clientID,vrep.simx_opmode_oneshot);
wheel_radifront=0.03;
ppose=[];
pposetrue=[]
position=[0 0 0];
t=10;
initPos=[0,0,0];
sample = 100;
Accel_sample = zeros(sample,3);
Pos_sample =zeros(sample,3);
getSample = 1;
Pos_sample(getSample,:)=initPos
checkFull = false;
delta_t=0.05;
run = true;
GGPS=[];
GPSs=[];
 if (clientID ~=-1)
    disp('Connected');
    [returnCode,Orobot]=vrep.simxGetObjectHandle(clientID,'Robotpose',vrep.simx_opmode_blocking)
    [returnCode,Robot]=vrep.simxGetObjectHandle(clientID,'Robot',vrep.simx_opmode_blocking );
    [returnCode,left_Motor]=vrep.simxGetObjectHandle(clientID,'motor_left',vrep.simx_opmode_blocking );
    [returnCode,right_Motor]=vrep.simxGetObjectHandle(clientID,'motor_right',vrep.simx_opmode_blocking );
    [returnCode,time]=vrep.simxGetFloatSignal(clientID,'SimulationTime',vrep.simx_opmode_streaming);
    [returnCode,position]=vrep.simxGetObjectPosition(clientID,Robot,-1,vrep.simx_opmode_streaming)
    [returnCode,orientation]=vrep.simxGetObjectOrientation(clientID,Orobot,-1,vrep.simx_opmode_streaming);
    [returnCode,GPS(1)]=vrep.simxGetFloatSignal(clientID,'GPS1',vrep.simx_opmode_streaming);
    [returnCode,GPS(2)]=vrep.simxGetFloatSignal(clientID,'GPS2',vrep.simx_opmode_streaming);
    [returnCode,GPS(3)]=vrep.simxGetFloatSignal(clientID,'GPS3',vrep.simx_opmode_streaming);
    [returnCode,Accel(1)]=vrep.simxGetFloatSignal(clientID,'Accel1',vrep.simx_opmode_streaming);
    [returnCode,Accel(2)]=vrep.simxGetFloatSignal(clientID,'Accel2',vrep.simx_opmode_streaming);
    [returnCode,Accel(3)]=vrep.simxGetFloatSignal(clientID,'Accel3',vrep.simx_opmode_streaming);
    [returnCode,Vel(1)]=vrep.simxGetFloatSignal(clientID,'Velocity1',vrep.simx_opmode_streaming);
    [returnCode,Vel(2)]=vrep.simxGetFloatSignal(clientID,'Velocity2',vrep.simx_opmode_streaming);
    [returnCode,Vel(3)]=vrep.simxGetFloatSignal(clientID,'Velocity3',vrep.simx_opmode_streaming);
    [returnCode,observed_Vel,]=vrep.simxGetObjectVelocity(clientID,Orobot,vrep.simx_opmode_streaming);
%    [returnCode,observed_Vel,]=vrep.simxGetObjectVelocity(clientID,robot,vrep.simx_opmode_streaming);
%   
    pause(0.05);
    while run== true
    [returnCode,time]=vrep.simxGetFloatSignal(clientID,'SimulationTime',vrep.simx_opmode_buffer);
    [returnCode,GPS(1)]=vrep.simxGetFloatSignal(clientID,'GPS1',vrep.simx_opmode_buffer);
    [returnCode,GPS(2)]=vrep.simxGetFloatSignal(clientID,'GPS2',vrep.simx_opmode_buffer);
    [returnCode,GPS(3)]=vrep.simxGetFloatSignal(clientID,'GPS3',vrep.simx_opmode_buffer);
    [returnCode,Accel(1)]=vrep.simxGetFloatSignal(clientID,'Accel1',vrep.simx_opmode_buffer);
    [returnCode,Accel(2)]=vrep.simxGetFloatSignal(clientID,'Accel2',vrep.simx_opmode_buffer);
    [returnCode,Accel(3)]=vrep.simxGetFloatSignal(clientID,'Accel3',vrep.simx_opmode_buffer);
    [returnCode,Vel(1)]=vrep.simxGetFloatSignal(clientID,'Velocity1',vrep.simx_opmode_buffer);
    [returnCode,Vel(2)]=vrep.simxGetFloatSignal(clientID,'Velocity2',vrep.simx_opmode_buffer);
    [returnCode,Vel(3)]=vrep.simxGetFloatSignal(clientID,'Velocity3',vrep.simx_opmode_buffer);
    %[returnCode,position]=vrep.simxGetObjectPosition(clientID,robot,-1,vrep.simx_opmode_streaming);
    [returnCode,orientation]=vrep.simxGetObjectOrientation(clientID,Orobot,-1,vrep.simx_opmode_buffer);
    if checkFull ==false
        pause(0.05);
        Pos_sample(getSample,:)=GPS;
        Accel_sample(getSample,:)=Accel;
        getSample = getSample + 1
        if getSample > sample 
        checkFull = true
        [R,Accel_cov_mat] = getCovMat(Pos_sample,Accel_sample)
        init_state = GPS;
        init_state(4) = 0;
        init_state(5) = 0;
        init_state(6) = 0;
        prev_state = transpose(init_state);
        prev_P =[1,0,0,1,0,0,
                0,1,0,0,1,0,
                0,0,1,0,0,1,
                1,0,0,1,0,0,
                0,1,0,0,1,0,
                0,0,1,0,0,1];
        end
    elseif checkFull == true
        %Kaman predict
        A = [1,0,0,delta_t,0,0,
            0,1,0,0,delta_t,0,
            0,0,1,0,0,delta_t,
            0,0,0,1,0,0,
            0,0,0,0,1,0,
            0,0,0,0,0,1]
        B = [(1/2)*(delta_t^2),0,0,
            0,(1/2)*(delta_t^2),0,
            0,0,(1/2)*(delta_t^2),
            delta_t,0,0,
            0,delta_t,0,
            0,0,delta_t]
        Q = [(1/4)*(delta_t^4),0,0,(1/2)*(delta_t^3),0,0,
            0,(1/4)*(delta_t^4),0,0,(1/2)*(delta_t^3),0,
            0,0,(1/4)*(delta_t^4),0,0,(1/2)*(delta_t^3),
            (1/2)*(delta_t^3),0,0,delta_t,0,0,
            0,(1/2)*(delta_t^3),0,0,delta_t,0,
            0,0,(1/2)*(delta_t^3),0,0,delta_t]*(Accel_cov_mat)
        [returnCode,Accel(1)]=vrep.simxGetFloatSignal(clientID,'Accel1',vrep.simx_opmode_buffer);
        [returnCode,Accel(2)]=vrep.simxGetFloatSignal(clientID,'Accel2',vrep.simx_opmode_buffer);
        [returnCode,Accel(3)]=vrep.simxGetFloatSignal(clientID,'Accel3',vrep.simx_opmode_buffer);   
        control_vec = transpose(Accel);
        xk = A*(prev_state)+B*control_vec;
        [returnCode,orientation]=vrep.simxGetObjectOrientation(clientID,Orobot,-1,vrep.simx_opmode_buffer);
       % pose =[position(1,1),position(1,2),orientation(1,3)];
        pose =[xk(1),xk(2),orientation(1,3)];
        P = A*(prev_P)*transpose(A)+(Q)
        P(1,2)=0;
        P(1,3)=0;
        P(1,5)=0;
        P(1,6)=0;
        P(2,1)=0;
        P(2,3)=0;
        P(2,4)=0;
        P(2,6)=0;
        P(3,1)=0;
        P(3,2)=0;
        P(3,4)=0;
        P(3,5)=0;
        P(4,2)=0;
        P(4,3)=0;
        P(4,5)=0;
        P(4,6)=0;
        P(5,1)=0;
        P(5,3)=0;
        P(5,4)=0;
        P(5,6)=0;
        P(6,1)=0;
        P(6,2)=0;
        P(6,4)=0;
        P(6,5)=0;
    
    if time>t &&time <=5+t
%    [returnCode,position]=vrep.simxGetObjectPosition(clientID,Robot,-1,vrep.simx_opmode_buffer)
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,left_Motor,0.2/wheel_radifront,vrep.simx_opmode_blocking);    
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,right_Motor,0.1/wheel_radifront,vrep.simx_opmode_blocking);
    elseif time>t+5 &&time <=10+t
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,left_Motor,0.1/wheel_radifront,vrep.simx_opmode_blocking);    
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,right_Motor,0.1/wheel_radifront,vrep.simx_opmode_blocking);   
    elseif time >10+t   
        run= false
    end
    [returnCode,datapose]=vrep.simxGetStringSignal(clientID,'Robotpose',vrep.simx_opmode_buffer);
    [returnCode,position]=vrep.simxGetObjectPosition(clientID,Robot,-1,vrep.simx_opmode_buffer)
    truepose =[position(1,1),position(1,2),orientation(1,3)];
    ppose=[ppose;pose];
    pposetrue=[pposetrue;truepose];
    GPSs=[GPS(1),GPS(2)];
    GGPS=[GGPS;GPSs];
     H = eye(6);
    [returnCode,GPS(1)]=vrep.simxGetFloatSignal(clientID,'GPS1',vrep.simx_opmode_buffer);
    [returnCode,GPS(2)]=vrep.simxGetFloatSignal(clientID,'GPS2',vrep.simx_opmode_buffer);
    [returnCode,GPS(3)]=vrep.simxGetFloatSignal(clientID,'GPS3',vrep.simx_opmode_buffer);
    observed = [GPS(1),GPS(2),GPS(3)];
    [returnCode,Vel(1)]=vrep.simxGetFloatSignal(clientID,'Velocity1',vrep.simx_opmode_buffer);
    [returnCode,Vel(2)]=vrep.simxGetFloatSignal(clientID,'Velocity2',vrep.simx_opmode_buffer);
    [returnCode,Vel(3)]=vrep.simxGetFloatSignal(clientID,'Velocity3',vrep.simx_opmode_buffer);    
    observed(4) = Vel(1);
    observed(5) = Vel(2);
    observed(6) = Vel(3);
    z = transpose(observed);
    y = z-H*xk;
    K = P*transpose(H)/(H*P*transpose(H)+R);         
    prev_state = xk+K*y
    prev_P = (eye(6)-K*H)*P;

    end
    end
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,left_Motor,0,vrep.simx_opmode_blocking);    
    [returnCode]=vrep.simxSetJointTargetVelocity( clientID,right_Motor,0,vrep.simx_opmode_blocking);
    vrep.simxStopSimulation(clientID, vrep.simx_opmode_oneshot_wait)


     % Stop the simulation
    vrep.simxFinish(clientID);
else
    % Connection Failed
    disp('Failed connecting to remote API server')
 end
 function [R,Accel_cov_mat]= getCovMat(Pos_sample,Accel_sample)
     Pos_deviation = Pos_sample - ones(length(Pos_sample),length(Pos_sample))*(Pos_sample)*(1/length(Pos_sample))
     Pos_cov = transpose(Pos_deviation)*(Pos_deviation)
     Accel_deviation = Accel_sample - ones(length(Accel_sample),length(Accel_sample))*(Accel_sample)*(1/length(Accel_sample))
     Accel_cov = transpose(Accel_deviation)*(Accel_deviation)
     R = diag([Pos_cov(1,1),Pos_cov(2,2),Pos_cov(3,3),0.1,0.1,0.1])
     Accel_cov_mat = diag([Accel_cov(1,1),Accel_cov(2,2),Accel_cov(3,3),Accel_cov(1,1),Accel_cov(2,2),Accel_cov(3,3)]) 
end
        end

        % Button pushed function: PlotTrajectoryINS
        function PlotTrajectoryINSPushed(app, event)
            global pposetrue ppose GGPS
            app.UIAxes.cla
            plot(app.UIAxes,pposetrue(:,1),pposetrue(:,2))
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,ppose(:,1),ppose(:,2))
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,GGPS(:,1),GGPS(:,2))
            hold(app.UIAxes, 'on')
            xlim(app.UIAxes,[-1,1]);
            ylim(app.UIAxes,[-1,1]);
            legend(app.UIAxes,'Truepos','Kalman','GPS','FontSize',12);
            
            figure()
            plot(ppose(:,1)-pposetrue(:,1))
            hold on
            xlabel('Time step')
            ylabel('Error x (m)')
            title('Error in X')
            %ylim([-0.1 0.1]);
            drawnow
            
            figure()
            plot(pposetrue(:,2));
            hold on
            plot(ppose(:,2))
            hold on
            plot(GGPS(:,2))
            hold on
            legend('truepos','Kaman','GPS','FontSize',12)
            title('Y')
            xlabel('Time step')
            ylabel('y (m)')
            grid on
            
            figure()
            plot(pposetrue(:,1));
            hold on
            plot(ppose(:,1))
            hold on
            plot(GGPS(:,1))
            hold on
            legend('truepos','Kaman','GPS','FontSize',12)
            title('x')
            xlabel('Time step')
            ylabel('x (m)')
            grid on
            
            figure()
            plot(ppose(:,2)-pposetrue(:,2))
            hold on
            xlabel('Time step')
            ylabel('Error y (m)')
            %ylim([-0.1 0.1]);
            title('Error in Y')
            drawnow
        end

        % Button pushed function: PathFollowingButton
        function PathFollowingButtonPushed(app, event)
    global path ppose Oppose 
    vrep=remApi('remoteApi');
    vrep.simxFinish(-1);
    clientID=vrep.simxStart('127.0.0.1',19997,true,true,5000,5);
    [returnCode]=vrep.simxStartSimulation(clientID,vrep.simx_opmode_oneshot)
    pose = [1,1,0];
    tireDiameter_m = 0.03;
    d = 0.0823;
    v = 0.1;
    pathx=path(:,1)/100;
    pathy=path(:,2)/100;
    goalPoints=[pathx pathy]
    ii = 1;
    lastGoal = pose(1:2);
    currentGoal = goalPoints(ii, :);
    atFinalGoal = 0;
    position=[1.000,1.000];
    state =1;
    wheel_radifront=0.035
    b=0.0823
    vTurn=0.03
    goalDetectedTol=1*pi/180;
    vref=0.1
    ppose=[]
    Oppose=[]
    delta_t=0.05
if (clientID>-1)
    % If connection successful
    disp('Connected')
    % Create handles for required V-Rep objects
    [returnCode,left_Motor]=vrep.simxGetObjectHandle(clientID,'motor_left',vrep.simx_opmode_blocking);
    [returnCode,right_Motor]=vrep.simxGetObjectHandle(clientID,'motor_right',vrep.simx_opmode_blocking);
    [returnCode,Orobot]=vrep.simxGetObjectHandle(clientID,'Robotpose',vrep.simx_opmode_blocking)
    [returnCode,Orierobot]=vrep.simxGetObjectOrientation(clientID,Orobot,-1,vrep.simx_opmode_streaming)
    [returnCode,Posirobot]=vrep.simxGetObjectPosition(clientID,Orobot,-1,vrep.simx_opmode_streaming)
   
    while(~atFinalGoal)
        [returnCode,datapose]=vrep.simxGetStringSignal(clientID,'Robotpose',vrep.simx_opmode_buffer)
        if (returnCode==vrep.simx_return_ok) % After initialization of streaming, it will take a few ms before the first value arrives, so check the return code
        position=vrep.simxUnpackFloats(datapose)
        end
       % [returnCode,position]=vrep.simxGetObjectOrientation(clientID,Orobot,-1,vrep.simx_opmode_buffer);
        [returnCode,orientation]=vrep.simxGetObjectOrientation(clientID,Orobot,-1,vrep.simx_opmode_buffer);
        [returnCode,position]=vrep.simxGetObjectPosition(clientID,Orobot,-1,vrep.simx_opmode_buffer)
        pose=[position(1,1),position(1,2),orientation(1,3)];
        if(state==1)
            %TODO:Check if it is pointing to goal
            [pointing,direction] = PointingToGoal(pose,lastGoal,currentGoal,goalDetectedTol);
                if pointing == true
                state=2
                end
            elseif (state==2)
            %TODO:Check if it is pointing to goal
            %TODO:Check if goal has been reached
            %TODO:Check if there's an object in front
            [pointing,direction] = PointingToGoal(pose,lastGoal,currentGoal,goalDetectedTol);
            atGoal=atGoalPoint(pose, currentGoal)    
                if pointing==false
                    state=1;
                elseif  atGoal ==true
                    state=3;
                end
            elseif (state==3)
                if(~atFinalGoal)
                    state =1;
                end
        end
        if(state==1)
            [wTurnL,wTurnR,turnTime]=precomputeTurn(vTurn,direction,wheel_radifront,b)
            wL=wTurnL;
            wR=wTurnR;
        elseif(state==2)
            wL=vref/wheel_radifront;
            wR=vref/wheel_radifront;
            t=0;
        elseif(state==3)
            wL=0
            wR=0
        end
        [returnCode]=vrep.simxSetJointTargetVelocity(clientID, left_Motor, wL,vrep.simx_opmode_blocking);
        [returnCode]=vrep.simxSetJointTargetVelocity(clientID, right_Motor, wR ,vrep.simx_opmode_blocking);
        % Update goal points if target goal reached
        atGoal=atGoalPoint(pose, currentGoal) 
        if atGoal ==true
            if(ii < length(goalPoints))  
                % increment goal point
                lastGoal = currentGoal;
                ii = ii + 1;
                currentGoal = goalPoints(ii, :);         
            else
                % No more goal points in list; at final goal
                atFinalGoal = 1;
            end
        end
        [returnCode,datapose]=vrep.simxGetStringSignal(clientID,'Robotpose',vrep.simx_opmode_buffer)
        if (returnCode==vrep.simx_return_ok) % After initialization of streaming, it will take a few ms before the first value arrives, so check the return code
        position=vrep.simxUnpackFloats(datapose)
        end
        [returnCode,orientation]=vrep.simxGetObjectOrientation(clientID,Orobot,-1,vrep.simx_opmode_buffer);
        [returnCode,position]=vrep.simxGetObjectPosition(clientID,Orobot,-1,vrep.simx_opmode_buffer)
        pose=[position(1,1),position(1,2),orientation(1,3)];
        %state=1;
       
        SL= wL* delta_t*wheel_radifront;
        SR= wR*delta_t*wheel_radifront;
        deltaS=(SL+SR)/2;
        deltatheta=(SR-SL)/(2*b)
        x=pose(1)+deltaS*cos(pose(3));
        y=pose(2)+deltaS*sin(pose(3));
        theta=pose(3)+ deltatheta;
        Odometrypose=[x,y,theta];
        %update to plot
        Oppose=[Oppose;Odometrypose];
        ppose=[ppose;position];
    end
    [returnCode]=vrep.simxStopSimulation(clientID,vrep.simx_opmode_oneshot_wait)
     vrep.simxFinish(clientID);
     

else
    % Connection Failed
    disp('Failed connecting to remote API server')
end

function [atGoal] = atGoalPoint(robotPose, goalPoint)
    goalRadius_m = 0.1;
    dist = sqrt((robotPose(1) - goalPoint(1))^2 + (robotPose(2) - goalPoint(2))^2);
    if abs(dist) < goalRadius_m
         atGoal = true;
    else atGoal=false;
    end
end

function [wL,wR,t]=precomputeTurn(vturn,direction,wheel_radifront,b)
    %vturn:turning speed
    %direction:1->Follow right,-1->Follow left
    %wheel_radifront,b:constructive parameters
    %Returns wheels velocities and turningtime
    wL=-direction*vturn/wheel_radifront
    wR=direction*vturn/wheel_radifront;
    t=b*(pi/2)/vturn
end
function [pointing,direction] = PointingToGoal(pose,lastGoal,currentGoal,tolerance)
    %pose:list with x,y,theta values of therobot
    %initPose:list with x,y of the initial position
    %goalPose:list with x,y of the goal position
    %tolerance:admissible angle(in radians)
    %Returns true or false and the turning direction(1 or -1)
    angle_diff=(atan2(currentGoal(1,2)-lastGoal(1,2),currentGoal(1,1)-lastGoal(1,1))-pose(1,3));
    if(abs(angle_diff)<tolerance)
        pointing=true
    else
        pointing=false
    end
    if(angle_diff>0)
        direction= +1
    else
        direction=-1
    end
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Trajectory')
            xlabel(app.UIAxes, 'X(m)')
            ylabel(app.UIAxes, 'Y(m)')
            app.PathFollowingButton = uibutton(app.UIFigure, 'push');
            app.PathFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @PathFollowingButtonPushed, true);
            app.PathFollowingButton.Text = 'Path Following';
            app.CreatePathButton = uibutton(app.UIFigure, 'push');
            app.CreatePathButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePathButtonPushed, true);
            app.CreatePathButton.Text = 'Create Path';
            app.BugAlgorithmButton = uibutton(app.UIFigure, 'push');
            app.BugAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @BugAlgorithmButtonPushed, true);
            app.BugAlgorithmButton.Text = 'Bug Algorithm';
            app.CreateMapButton = uibutton(app.UIFigure, 'push');
            app.CreateMapButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMapButtonPushed, true);
            app.CreateMapButton.Text = 'Create Map';
            app.PlotTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.PlotTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrajectoryButtonPushed, true);
            app.PlotTrajectoryButton.Text = 'Plot Trajectory';
            app.BugAlgorithmLabel = uilabel(app.UIFigure);
            app.BugAlgorithmLabel.Text = 'Bug Algorithm';
            app.AAlgorithmLabel = uilabel(app.UIFigure);
            app.AAlgorithmLabel.Text = 'A* Algorithm';
            app.RunKinematicButton = uibutton(app.UIFigure, 'push');
            app.RunKinematicButton.ButtonPushedFcn = createCallbackFcn(app, @RunKinematicButtonPushed, true);
            app.RunKinematicButton.Text = 'Run Kinematic';
            app.CheckKinematicLabel = uilabel(app.UIFigure);
            app.CheckKinematicLabel.Text = 'Check Kinematic';
            app.PlotTrajectoryButtonBug = uibutton(app.UIFigure, 'push');
            app.PlotTrajectoryButtonBug.ButtonPushedFcn = createCallbackFcn(app, @PlotTrajectoryButtonBugPushed, true);
            app.PlotTrajectoryButtonBug.Text = 'Plot Trajectory';
            app.PlotTrajectoryButtonAstar = uibutton(app.UIFigure, 'push');
            app.PlotTrajectoryButtonAstar.ButtonPushedFcn = createCallbackFcn(app, @PlotTrajectoryButtonAstarPushed, true);
            app.PlotTrajectoryButtonAstar.Text = 'Plot Trajectory';
            app.RunInverseKinematicButton = uibutton(app.UIFigure, 'push');
            app.RunInverseKinematicButton.ButtonPushedFcn = createCallbackFcn(app, @RunInverseKinematicButtonPushed, true);
            app.RunInverseKinematicButton.Text = 'Run Inverse Kinematic';
            app.INSButton = uibutton(app.UIFigure, 'push');
            app.INSButton.ButtonPushedFcn = createCallbackFcn(app, @INSButtonPushed, true);
            app.INSButton.Text = 'INS';
            app.CheckINSLabel = uilabel(app.UIFigure);
            app.CheckINSLabel.Text = 'Check INS';
            app.PlotTrajectoryINS = uibutton(app.UIFigure, 'push');
            app.PlotTrajectoryINS.ButtonPushedFcn = createCallbackFcn(app, @PlotTrajectoryINSPushed, true);
            app.PlotTrajectoryINS.Text = 'Plot Trajectory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vrepmatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end